classdef ComplexFourierSeries < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ComplexFourierSeriesUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        DominiodelTiempoPanel         matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        ControlesPanel                matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        CrculosdeModosCheckBox        matlab.ui.control.CheckBox
        VectoresdeModosCheckBox       matlab.ui.control.CheckBox
        RastrearLneasCheckBox         matlab.ui.control.CheckBox
        DetenerAnimacinButton         matlab.ui.control.Button
        NSpinner                      matlab.ui.control.Spinner
        NLabel                        matlab.ui.control.Label
        AnimarButton                  matlab.ui.control.Button
        LimpiarButton                 matlab.ui.control.Button
        DefinicinPanel                matlab.ui.container.Panel
        EqAxes                        matlab.ui.control.UIAxes
        UIAxesImag                    matlab.ui.control.UIAxes
        UIAxesAnimate                 matlab.ui.control.UIAxes
        UIAxesReal                    matlab.ui.control.UIAxes
        UIAxesComplex                 matlab.ui.control.UIAxes
        FrequencyDomainComplexFourierSeriesCoefficientsPanel  matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        UIAxesImagCn                  matlab.ui.control.UIAxes
        UIAxesRealCn                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
               
        P = 1; % Period (determines the axis limits)
        Nmodes % Number of modes (set by spinner)
        Nt = 250; % Number of temporal points

        imLims = 2*[-1,1] % Im limits on complex plane
        reLims = 2*[-1,1] % Re limits on complex plane       

        lwSeries = 1.25; % Linewidth of time series
        lwAnimate = 1; % Line width of the animation
        lwCircle = 1; % Line width of vectors
        lwVector = 1; % Line width of circles

        % Colors
        timeColors = [     0    0.4470    0.7410;
                      0.7350    0.1000    0.2500;
                      0.4940    0.1840    0.5560]+0.1;
        traceLineColors = [      0    0.3470    0.5410;
                            0.4350    0.0500    0.1500]+0.1;
        coefColors = [0.9290    0.6940    0.1250;
                      0.9290    0.6940    0.1250];

        % How dim to make the shadows (closer to zero = dimmer)
        shadowAdd = 0.7;
        shadowMult = 0.3;

        % Plot objects
        realMarkers % Real coef markers
        imagMarkers % Imag coef markers

        spectrumLinesRe % stem plot for spectrum coeffs (re)
        spectrumLinesIm % stem plot for spectrum coeffs (im)
        
        realPlot      % Line plot for real time series
        complexPlot   % Line plot for complex time series
        imagPlot      % Line plot from imaginary time series

        stopAnimation % Flag to stop the animation (!UNUSED)

        % Math objects
        nvec % Vector of mode indices
        expBasis % Complex exponential basis
        tvec % time array 
        fvec % function values
        cvec % Coefficient values

        %#ok<*INUSL> 
        %#ok<*INUSD> 
    end
    
    methods (Access = private)
        %% These functions set up the plots, etc.

        function setUp(app)
            % The main setup function for the whole app
           
            % Clear the animation axes
            cla(app.UIAxesAnimate)
            app.UIAxesAnimate.Visible = "off";

            % Get the initial number of modes
            app.Nmodes = app.NSpinner.Value;

            % Set up the complex Fourier basis
            [app.expBasis, app.tvec, app.nvec] = cfBasis(app,app.Nmodes,app.Nt,app.P);

            % Create the markers
            app.setUpFreqAxes
            app.setUpTimeDomainAxes

            % Create equation
            app.createEquationAxes

            % Update function
            app.coefMoved

            % Re-enable the the animate button
            drawnow
            app.AnimarButton.Enable = true;
        end

        function setUpFreqAxes(app)
            % Sets up the coefficient axes 

            % Clear the axes
            cla(app.UIAxesRealCn)
            cla(app.UIAxesImagCn)
            app.realMarkers = {};
            app.imagMarkers = {};

            % AXIS 1: real coefficients
            hold(app.UIAxesRealCn,'on')
            % Create stem plot
            app.spectrumLinesRe = stem(app.UIAxesRealCn,app.nvec,app.nvec*0,...
                'linewidth',1,'color',app.coefColors(1,:),'marker','none');
            % Add markers
            for k = 1:numel(app.nvec)
                app.realMarkers{k} = DraggablePoint2D(app.UIAxesRealCn,...
                                        app.nvec(k),0,@app.coefMoved,2);
                set(app.realMarkers{k}.lineHandle,... 
                     'markerfacecolor',app.coefColors(1,:),...
                     'color',[0.8,0.8,0.8]);   
            end
            hold(app.UIAxesRealCn,'off')

            % AXIS 2: sine coefficients (b)
            hold(app.UIAxesImagCn,'on')
            % Create stem plot
            app.spectrumLinesIm = stem(app.UIAxesImagCn,app.nvec,app.nvec*0,...
                'linewidth',1,'color',app.coefColors(2,:),'marker','none');
            % Add markers
            for k = 1:numel(app.nvec)
                app.imagMarkers{k} = DraggablePoint2D(app.UIAxesImagCn,...
                                        app.nvec(k),0,@app.coefMoved,2);
                set(app.imagMarkers{k}.lineHandle,... 
                     'markerfacecolor',app.coefColors(2,:),...
                     'color',[0.8,0.8,0.8]);   
            end
            hold(app.UIAxesImagCn,'off')

            % Change graph appearance: Real coefficients
            xlim(app.UIAxesRealCn,app.nvec([1,end]))
            xticks(app.UIAxesRealCn,app.nvec)
            xlabel(app.UIAxesRealCn,'$n$','interpreter','latex')
            ylabel(app.UIAxesRealCn,'Re$(c_n)$','interpreter','latex')
            disableDefaultInteractivity(app.UIAxesRealCn)
            axtoolbar(app.UIAxesRealCn,{});
            ytickangle(app.UIAxesRealCn,90)

            % Change graph appearance: Imaginary coefficients
            xlim(app.UIAxesImagCn,app.nvec([1,end]))
            xticks(app.UIAxesImagCn,app.nvec)
            xlabel(app.UIAxesImagCn,'$n$','interpreter','latex')
            ylabel(app.UIAxesImagCn,'Im$(c_n)$','interpreter','latex')
            disableDefaultInteractivity(app.UIAxesImagCn)
            axtoolbar(app.UIAxesImagCn,{});
            ytickangle(app.UIAxesImagCn,90)

        end

        function setUpTimeDomainAxes(app)
            % Sets up the time domain axes (real, complex, and imag)
                        
            % Clear the axes
            cla(app.UIAxesComplex)
            cla(app.UIAxesReal)
            cla(app.UIAxesImag)

            % Real time series line
            app.realPlot = plot(app.UIAxesReal,app.tvec*0,app.tvec,...
                'linewidth',app.lwSeries,'color',app.timeColors(1,:));
            app.imagPlot = plot(app.UIAxesImag,app.tvec,app.tvec*0,...
                'linewidth',app.lwSeries,'color',app.timeColors(2,:));     
            app.complexPlot = plot(app.UIAxesComplex,app.tvec*0,app.tvec*0,...
                'linewidth',app.lwSeries,'color',app.timeColors(3,:));     

            % Change graph appearance: complex plane
            xlim(app.UIAxesComplex,app.reLims)
            ylim(app.UIAxesComplex,app.imLims)
            xticks(app.UIAxesComplex,[app.reLims(1), mean(app.reLims),app.reLims(2)])
            yticks(app.UIAxesComplex,[app.imLims(1), mean(app.imLims),app.imLims(2)])
            xlabel(app.UIAxesComplex,'Re$(f)$','interpreter','latex')
            ylabel(app.UIAxesComplex,'Im$(f)$','interpreter','latex')
            disableDefaultInteractivity(app.UIAxesComplex)
            axtoolbar(app.UIAxesComplex,{});
            ytickangle(app.UIAxesComplex,90)

            % Graph appearance: real 
            xlim(app.UIAxesReal,app.reLims)
            ylim(app.UIAxesReal,[app.tvec(1),app.tvec(end)])
            xticks(app.UIAxesReal,[app.reLims(1), mean(app.reLims),app.reLims(2)])
            yticks(app.UIAxesReal,[app.tvec(1), mean(app.tvec),app.tvec(end)])
            xlabel(app.UIAxesReal,"")
            ylabel(app.UIAxesReal,'$t$','interpreter','latex')
%             title(app.UIAxesReal,"Real time series")
            disableDefaultInteractivity(app.UIAxesReal)
            axtoolbar(app.UIAxesReal,{});
            ytickangle(app.UIAxesReal,90)

            % Graph appearance: imaginary
            ylim(app.UIAxesImag,app.imLims)
            xlim(app.UIAxesImag,[app.tvec(1),app.tvec(end)])
            yticks(app.UIAxesImag,[app.imLims(1), mean(app.imLims),app.imLims(2)])
            xticks(app.UIAxesImag,[app.tvec(1), mean(app.tvec),app.tvec(end)])
            xlabel(app.UIAxesImag,'$t$','interpreter','latex')
            ylabel(app.UIAxesImag,"")
%             title(app.UIAxesImag,"Imaginary time series")
            disableDefaultInteractivity(app.UIAxesImag)
            axtoolbar(app.UIAxesImag,{});
            ytickangle(app.UIAxesImag,90)

            % Animation axes
            axis(app.UIAxesAnimate,"off")
        end


        function coefMoved(app,xp,yp)
             % Executes when a Fourier coefficient marker is moved
             % Calls the functions to update the time series
            [cnRe,cnIm] = pullFourierMarkers(app);
            f = evalFourier(app,cnRe,cnIm);

            updateTimeSeries(app,f)
            updateSpectrum(app,cnRe,cnIm)

        end

        function [cnRe,cnIm] = pullFourierMarkers(app)
            % Pulls the coefficients from the moveable markers
            
            cnRe = zeros(1,numel(app.realMarkers));
            cnIm = zeros(1,numel(app.imagMarkers));
                for k = 1:numel(app.realMarkers)
                    [~,cnRe(k)] = app.realMarkers{k}.getPos();
                    [~,cnIm(k)] = app.imagMarkers{k}.getPos();
                end
            app.cvec = cnRe + cnIm*1i;
        end

        
        function updateTimeSeries(app,f)
            % Updates the time series plots
            
            % Update the app storage
            app.fvec = f;

            % Complex plot
            app.complexPlot.XData = real(f);
            app.complexPlot.YData = imag(f);
            % Real plot
            app.realPlot.XData = real(f);
            % Imaginary plot
            app.imagPlot.YData = imag(f);
        end

        % Updates the spectrum plots
        function updateSpectrum(app,a,b)
            app.spectrumLinesRe.YData = a;
            app.spectrumLinesIm.YData = b;
        end


        %% Animation functions
        function animatePlot(app)
            % The main animation function

%             app.DetenerAnimacinButton.Visible = true;
            % Disable the interactivity while the animation is running
            app.stopAnimation = false;
            app.UIAxesAnimate.Visible = "on";
            app.AnimarButton.Enable = false;
            axis(app.UIAxesAnimate,"off")

            % decrease the visibility of the time traces to shadows
            shadowColors = app.timeColors.*app.shadowMult + app.shadowAdd;
            shadowColors(shadowColors>1) = 1;

            app.realPlot.Color = shadowColors(1,:);
            app.imagPlot.Color = shadowColors(2,:);
            app.complexPlot.Color = shadowColors(3,:);

            % Create trace plots
            hold(app.UIAxesReal,"on")
            reLine = plot(app.UIAxesReal,0,0,"linewidth",app.lwSeries,...
                "color",app.timeColors(1,:));
            hold(app.UIAxesReal,"off")

            hold(app.UIAxesImag,"on")
            imLine = plot(app.UIAxesImag,0,0,"linewidth",app.lwSeries,...
                "color",app.timeColors(2,:));
            hold(app.UIAxesImag,"off")

            hold(app.UIAxesComplex,"on")
            cmplxLine = plot(app.UIAxesComplex,0,0,"linewidth",app.lwSeries,...
                "color",app.timeColors(3,:));
            hold(app.UIAxesComplex,"off")


            % Create straight line animation plots
            hold(app.UIAxesAnimate,"on")
            vertLine = plot(app.UIAxesAnimate,[0,0],[0,0],'-',...
                'linewidth',app.lwAnimate,'color',app.traceLineColors(1,:));
            horzLine = plot(app.UIAxesAnimate,[0,0],[0,0],'-',...
                'linewidth',app.lwAnimate,'color',app.traceLineColors(2,:));
            hold(app.UIAxesAnimate,"off")


            % Create handles for vectors/circles
            colors = gray(3*app.Nmodes+1 );
            hold(app.UIAxesComplex,"on")
            for j = 1:(2*app.Nmodes+1)
                modeQuivers{j} = quiver(app.UIAxesComplex,0,0,0,0,'off',...
                    "color",colors(j,:),"linewidth",app.lwVector);
                circHandles{j} = plot(app.UIAxesComplex,0,0,...
                    "color",colors(j,:),"linewidth",app.lwCircle);
            end
            thetaCirc = linspace(0,2*pi,50);
            hold(app.UIAxesComplex,"off")


            for k = 1:numel(app.tvec)

                t = app.tvec(k);
                f = app.fvec(k);

                % This code creates the connecting lines
                pComplex = [real(f),imag(f)];
                pReal = [real(f),t];
                pImag = [t,imag(f)];
                
                pCFig = app.figPos(app.UIAxesComplex,pComplex);
                pRFig = app.figPos(app.UIAxesReal,pReal);
                pIFig = app.figPos(app.UIAxesImag,pImag);

                pCanim = app.axisPos(app.UIAxesAnimate,pCFig);
                pRanim = app.axisPos(app.UIAxesAnimate,pRFig);
                pIanim = app.axisPos(app.UIAxesAnimate,pIFig);

                if(app.RastrearLneasCheckBox.Value)
                    vertLine.XData = [pCanim(1),pRanim(1)];
                    vertLine.YData = [pCanim(2),pRanim(2)];
                    
                    horzLine.XData = [pCanim(1),pIanim(1)];
                    horzLine.YData = [pCanim(2),pIanim(2)];
                end

                cmplxLine.XData = real(app.fvec(1:k));
                cmplxLine.YData = imag(app.fvec(1:k));

                reLine.XData = real(app.fvec(1:k));
                reLine.YData = app.tvec(1:k);

                imLine.XData = app.tvec(1:k);
                imLine.YData = imag(app.fvec(1:k));


                % This code creates the vectors/circles
                fnStart = 0;
                for j = 1:(2*app.Nmodes+1)
                    
                    % Retrieve fourier values
                    nSeq = [(1:app.Nmodes);-(1:app.Nmodes)];
                    nOrder = [0;nSeq(:)]';
                    nIdx = nOrder+app.Nmodes+1;
                    nkBasis = app.expBasis(k,nIdx(j));
                    cn = app.cvec(nIdx(j));
                    fn = cn*nkBasis;
                    
                    % Show the quiver arrows (if requested)
                    if(app.VectoresdeModosCheckBox.Value)
                        modeQuivers{j}.XData = real(fnStart);
                        modeQuivers{j}.YData = imag(fnStart);
                        modeQuivers{j}.UData = real(fn);
                        modeQuivers{j}.VData = imag(fn);
                    else
                        modeQuivers{j}.Visible = false;
                    end

                    % Update the circle (if necessary)
                    if(app.CrculosdeModosCheckBox.Value)
                        circHandles{j}.Visible = true;
                        app.updateCircle(circHandles{j},abs(fn),[real(fnStart),imag(fnStart)],thetaCirc)
                    else
                        circHandles{j}.Visible = false;
                    end
               
                    % Update the arrow starting location
                    fnStart = fnStart+fn;
                    
                end

%               % Option to stop animation
%                 if(mod(k,20)==0)
%                     drawnow
%                     if(app.stopAnimation)
%                         break
%                     end
%                 end
                pause(0)
            end


            % Clear the animation axes
            cla(app.UIAxesAnimate)
            app.UIAxesAnimate.Visible = "off";
%             app.DetenerAnimacinButton.Visible = false;

            % Put regular colors back on plots
            app.realPlot.Color = app.timeColors(1,:);
            app.imagPlot.Color = app.timeColors(2,:);
            app.complexPlot.Color = app.timeColors(3,:);

            % Clear extra plots
            delete(cmplxLine)
            delete(reLine)
            delete(imLine)

            for j = 1:(2*app.Nmodes+1)
                delete(modeQuivers{j})
                delete(circHandles{j})
            end
            drawnow

            % Re-enable the the animate button
            app.AnimarButton.Enable = true;
        end


        function pFig = figPos(app,ax,p)
            % This function computes the position of a point in the figure
            % window so that it can be used to draw between axes

            axUnits = ax.Units;
            ax.Units = 'normalized';
            pos = ax.InnerPosition;

            % Shifted so that the origin is at the lower-left corner
            x = p(1)-ax.XLim(1);
            y = p(2)-ax.YLim(1);
            
            % Compute the position of the point in the figure
            xFig = x*(pos(3))/(ax.XLim(2) - ax.XLim(1)) + pos(1);
            yFig = y*(pos(4))/(ax.YLim(2) - ax.YLim(1)) + pos(2);
            
            pFig = [xFig,yFig];
            ax.Units = axUnits;
        end

        function pAxis = axisPos(app,ax,pFig)
            % This function computes the position in a particular axes from
            % the figure window. This is used to draw between plots.

            axUnits = ax.Units;
            ax.Units = 'normalized';
            pos = ax.InnerPosition;

            x = (pFig(1) - pos(1))*(ax.XLim(2) - ax.XLim(1))/pos(3);
            y = (pFig(2) - pos(2))*(ax.YLim(2) - ax.YLim(1))/pos(4);

            pAxis = [x+ax.XLim(1), y+ax.YLim(1)];

            ax.Units = axUnits;
        end

      
        function updateCircle(app,pHandle,r,c,theta)
            pHandle.XData = c(1) + r*cos(theta);
            pHandle.YData = c(2) + r*sin(theta);
        end


        %% Math functions
        function [expBasis, tvec, nv] = cfBasis(app,Nfreq,Nt,P) 
            % Compute the complex Fourier series basis
            % Nf is the number of frequencies
            % Nt is the number of time points
            % P is the period (also the final time)

            % Time domain
            tvec = linspace(0,P,Nt)';
            % Frequency indices
            nv = -Nfreq:Nfreq;
            % Exponential basis
            expBasis = exp(2*pi*1i/P*nv.*tvec);

        end

        % Evaluate Fourier series
        function f = evalFourier(app,cnRe,cnIm)
            cn = cnRe + cnIm*1i;
            % Compute the fourier series
            f = sum(cn.*app.expBasis,2);
        end

                
        %% Controls functions

        function createEquationAxes(app)
            % Adjust the equation axes
            axis(app.EqAxes,"off")
            disableDefaultInteractivity(app.EqAxes)
            axtoolbar(app.EqAxes,{});
            eq = "$$f(t) = \sum_{-N}^N c_n e^{ 2\pi i n t}$$";
            cla(app.EqAxes)
            text(app.EqAxes,0.45,0.5,eq,"color",[0,0,0]*0.95,"Interpreter","latex",...
                "HorizontalAlignment","center",'FontSize',14)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setUp
        end

        % Value changed function: NSpinner
        function NSpinnerValueChanged(app, event)
            app.setUp
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.setUp
        end

        % Button pushed function: AnimarButton
        function AnimarButtonPushed(app, event)
            app.animatePlot
        end

        % Button pushed function: DetenerAnimacinButton
        function DetenerAnimacinButtonPushed(app, event)
            app.stopAnimation = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ComplexFourierSeriesUIFigure = uifigure('Visible', 'off');
            app.ComplexFourierSeriesUIFigure.Name = 'Complex Fourier Series';
            app.GridLayout = uigridlayout(app.ComplexFourierSeriesUIFigure);
            app.FrequencyDomainComplexFourierSeriesCoefficientsPanel = uipanel(app.GridLayout);
            app.FrequencyDomainComplexFourierSeriesCoefficientsPanel.Title = 'Dominio de la Frecuencia: Coeficientes Complejos de la Serie de Fourier';
            app.GridLayout3 = uigridlayout(app.FrequencyDomainComplexFourierSeriesCoefficientsPanel);
            app.UIAxesRealCn = uiaxes(app.GridLayout3);
            xlabel(app.UIAxesRealCn, 't')
            ylabel(app.UIAxesRealCn, 'y')
            zlabel(app.UIAxesRealCn, 'Z')
            app.UIAxesRealCn.XLim = [0 8];
            app.UIAxesRealCn.YLim = [-1 1];
            app.UIAxesImagCn = uiaxes(app.GridLayout3);
            xlabel(app.UIAxesImagCn, 't')
            ylabel(app.UIAxesImagCn, 'y')
            zlabel(app.UIAxesImagCn, 'Z')
            app.UIAxesImagCn.XLim = [0 8];
            app.UIAxesImagCn.YLim = [-1 1];
            app.DominiodelTiempoPanel = uipanel(app.GridLayout);
            app.DominiodelTiempoPanel.Title = 'Dominio del Tiempo';
            app.GridLayout4 = uigridlayout(app.DominiodelTiempoPanel);
            app.UIAxesComplex = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesComplex, 't')
            ylabel(app.UIAxesComplex, 'y')
            zlabel(app.UIAxesComplex, 'Z')
            app.UIAxesComplex.XLim = [0 1];
            app.UIAxesComplex.YLim = [-2 2];
            app.UIAxesComplex.ZLim = [0 1];
            app.UIAxesReal = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesReal, 't')
            ylabel(app.UIAxesReal, 'y')
            zlabel(app.UIAxesReal, 'Z')
            app.UIAxesReal.XLim = [0 1];
            app.UIAxesReal.YLim = [-2 2];
            app.UIAxesReal.ZLim = [0 1];
            app.UIAxesAnimate = uiaxes(app.GridLayout4);
            app.UIAxesAnimate.XLim = [0 1];
            app.UIAxesAnimate.YLim = [0 1];
            app.UIAxesAnimate.Visible = 'off';
            app.UIAxesImag = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesImag, 't')
            ylabel(app.UIAxesImag, 'y')
            zlabel(app.UIAxesImag, 'Z')
            app.UIAxesImag.XLim = [0 1];
            app.UIAxesImag.YLim = [-2 2];
            app.UIAxesImag.ZLim = [0 1];
            app.ControlesPanel = uipanel(app.GridLayout4);
            app.ControlesPanel.Title = 'Controles';
            app.GridLayout2 = uigridlayout(app.ControlesPanel);
            app.EqAxes = uiaxes(app.GridLayout2);
            app.EqAxes.Visible = 'off';
            app.DefinicinPanel = uipanel(app.GridLayout2);
            app.DefinicinPanel.Title = 'Definición';
            app.LimpiarButton = uibutton(app.GridLayout2, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.AnimarButton = uibutton(app.GridLayout2, 'push');
            app.AnimarButton.ButtonPushedFcn = createCallbackFcn(app, @AnimarButtonPushed, true);
            app.AnimarButton.Text = 'Animar';
            app.NLabel = uilabel(app.GridLayout2);
            app.NLabel.Text = 'N:';
            app.NSpinner = uispinner(app.GridLayout2);
            app.NSpinner.ValueChangedFcn = createCallbackFcn(app, @NSpinnerValueChanged, true);
            app.NSpinner.Value = 3;
            app.DetenerAnimacinButton = uibutton(app.GridLayout2, 'push');
            app.DetenerAnimacinButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerAnimacinButtonPushed, true);
            app.DetenerAnimacinButton.Visible = 'off';
            app.DetenerAnimacinButton.Text = 'Detener Animación';
            app.RastrearLneasCheckBox = uicheckbox(app.GridLayout2);
            app.RastrearLneasCheckBox.Text = 'Rastrear Líneas';
            app.RastrearLneasCheckBox.Value = true;
            app.VectoresdeModosCheckBox = uicheckbox(app.GridLayout2);
            app.VectoresdeModosCheckBox.Text = 'Vectores de Modos';
            app.VectoresdeModosCheckBox.Value = true;
            app.CrculosdeModosCheckBox = uicheckbox(app.GridLayout2);
            app.CrculosdeModosCheckBox.Text = 'Círculos de Modos';
            % Show the figure after all components are created
            app.ComplexFourierSeriesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComplexFourierSeries

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ComplexFourierSeriesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ComplexFourierSeriesUIFigure)
        end
    end
end