classdef MagnitudePhase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FourierSeriesMagnitudeandPhaseUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        DominiodelTiempoSeriedeMagnitudyFasePanel  matlab.ui.container.Panel
        GridLayout5                matlab.ui.container.GridLayout
        DefinitionPanelMagPhase    matlab.ui.container.Panel
        UIAxesMagPhase             matlab.ui.control.UIAxes
        EqAxesMagPhase             matlab.ui.control.UIAxes
        DominiodelTiempoSeriedeSenoyCosenoPanel  matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        DefinitionPanelSineCosine  matlab.ui.container.Panel
        UIAxesSineCosine           matlab.ui.control.UIAxes
        EqAxesSineCosine           matlab.ui.control.UIAxes
        DominiodelFrecuenciaSeriedeMagnitudyFasePanel  matlab.ui.container.Panel
        GridLayout6                matlab.ui.container.GridLayout
        NSpinnerMagPhase           matlab.ui.control.Spinner
        NSpinner_2Label            matlab.ui.control.Label
        ClearButtonMagPhase        matlab.ui.control.Button
        UIAxes_phin                matlab.ui.control.UIAxes
        UIAxes_An                  matlab.ui.control.UIAxes
        DominiodelFrecuenciaSeriedeSenoyCosenoPanel  matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        ClearButtonSineCosine      matlab.ui.control.Button
        NSpinnerSineCosine         matlab.ui.control.Spinner
        NLabel                     matlab.ui.control.Label
        UIAxes_an                  matlab.ui.control.UIAxes
        UIAxes_bn                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
               
        P = 1; % Period (determines the axis limits)
        Nmodes % Number of modes (set by spinner)
        Nt = 250; % Number of temporal points

        reLims = 2*[-1,1] % Real axis limits

        magLims = [0,2];
        phaseLims = [-pi,pi];

        lwSeries = 1.25; % Linewidth of time series
        eqFontSize = 16;

        % Colors
        timeColors = [     0    0.4470    0.7410;
                      0.4660    0.6740    0.1880]
        fadeValue = 0.8;
        traceLineColors = [      0    0.3470    0.5410;
                            0.4350    0.0500    0.1500]+0.1;
        coefColors = [     0    0.4470    0.7410;
                      0.8500    0.3250    0.0980;
                      0.9290    0.6940    0.1250;
                      0.4940    0.1840    0.5560];

        % Plot objects
        markers_an 
        markers_bn
        markers_An
        markers_phin
        
        plot_an       % Stem plot for an
        plot_bn       % Stem plot for bn
        plot_An       % Stem plot for An
        plot_phin     % Stem plot for phin
        
        realPlot1     % First time series plot
        realPlot2     % Mag/phase time series plot

        realFade1   % Faded version of plot 2
        realFade2   % Faded version of plot 1

        % Math objects
        tvec % time array 

        % For the 
        nvec % Vector of mode indices
        fbcos % Cosine basis
        fbsin % Sine basis

        % For the magnitude/phase calculation
        nvecMP % Vector of mode indices
        fbcosMP % Cosine basis
        fbsinMP % Sine basis

        %#ok<*INUSL> 
        %#ok<*INUSD> 
    end
    
    methods (Access = private)
        %% These functions set up the plots, etc.
        % This first set is specific to the two vertical panes: for the 
        % sine/cosine and phase/magnitude
        function setUpSinCos(app)
            % Set up the sine/cosine frequency domain axes
           
            % Compute the basis functions
            Nfreq = app.NSpinnerSineCosine.Value;
            [app.fbcos, app.fbsin, app.tvec, app.nvec] = sinCosBasis(app,Nfreq,app.Nt,app.P);

            % Set up an axis
            anvec = 0:Nfreq;
            color = app.coefColors(1,:);
            [app.markers_an,app.plot_an] = app.setUpFreqAxes(app.UIAxes_an,...
                anvec,color,app.markers_an,app.plot_an,"$n$","$a_n$",@app.coef1Moved);

            % Set up bn axis
            bnvec = 0:Nfreq;
            color = app.coefColors(2,:);
            [app.markers_bn,app.plot_bn] = app.setUpFreqAxes(app.UIAxes_bn,...
                bnvec,color,app.markers_bn,app.plot_bn,"$n$","$b_n$",@app.coef1Moved);
            app.markers_bn{1}.lineHandle.Visible = "off";


        end

        function setUpMagPhase(app)
            % Set up the magnitude/phase frequency domain axes
           
            % Compute the basis functions
            Nfreq = app.NSpinnerMagPhase.Value;
            [app.fbcosMP, app.fbsinMP, app.tvec, app.nvecMP] = sinCosBasis(app,Nfreq,app.Nt,app.P);

            % Set up mag axis
            anvec = 0:Nfreq;
            color = app.coefColors(3,:);
            [app.markers_An,app.plot_An] = app.setUpFreqAxes(app.UIAxes_An,...
                anvec,color,app.markers_An,app.plot_An,"$n$","$A_n$",@app.coef2Moved);
            ylim(app.UIAxes_An,app.magLims)

            % Set up phase axis
            bnvec = 0:Nfreq;
            color = app.coefColors(4,:);
            [app.markers_phin,app.plot_phin] = app.setUpFreqAxes(app.UIAxes_phin,...
                bnvec,color,app.markers_phin,app.plot_phin,"$n$","$\phi_n$",@app.coef2Moved);
            app.markers_phin{1}.lineHandle.Visible = "off";
            ylim(app.UIAxes_phin,app.phaseLims)
            phiTicks = app.phaseLims(1):diff(app.phaseLims)/4:app.phaseLims(end);
            yticks(app.UIAxes_phin,phiTicks)
            yticklabels(app.UIAxes_phin,["-\pi","",0,"","\pi"]);

   
        end

        function createRealPlots(app)
            % Create the time domain axes/plots

            % Set real axes sine/cos
            setUpRealAxes(app,app.UIAxesSineCosine,"$t$","$f$");
            eq = "$$f(t) = \frac{a_0}{2}  + \sum_{n=1}^\infty a_n \cos\left(" + ...
                " 2 \pi n t \right) + b_n \sin \left(  2 \pi n t \right)$$";
            app.setupEquationAxes(app.EqAxesSineCosine,eq,app.eqFontSize)

            % Set real axes mag/phase
            setUpRealAxes(app,app.UIAxesMagPhase,"$t$","$f$");
            eq = "$$f(t) = \frac{A_0}{2} +  \sum_{n=1}^{\infty} A_n " + ...
                    "\cos\left(  2 \pi n t - \phi_n \right)$$";
            app.setupEquationAxes(app.EqAxesMagPhase,eq,app.eqFontSize)

            % Create plot objects
            hold(app.UIAxesSineCosine,"on")
            app.realFade2 = plot(app.UIAxesSineCosine,app.tvec,app.tvec*0,...
                'linewidth',app.lwSeries,'color',(1-app.fadeValue)*app.timeColors(2,:)+app.fadeValue*[1 1 1]);
            app.realPlot1 = plot(app.UIAxesSineCosine,app.tvec,app.tvec*0,...
                'linewidth',app.lwSeries,'color',app.timeColors(1,:));
            hold(app.UIAxesSineCosine,"off")
            hold(app.UIAxesMagPhase,"on")
            app.realFade1 = plot(app.UIAxesMagPhase,app.tvec,app.tvec*0,...
                'linewidth',app.lwSeries,'color',(1-app.fadeValue)*app.timeColors(1,:)+app.fadeValue*[1 1 1]);
            app.realPlot2 = plot(app.UIAxesMagPhase,app.tvec,app.tvec*0,...
                'linewidth',app.lwSeries,'color',app.timeColors(2,:));
            hold(app.UIAxesMagPhase,"off")
        end

        
        function coef1Moved(app,xp,yp)
            % Executes when a Fourier coefficient marker is moved
            % In the sine-cosine definition 

            % Pull the coefficients
            an = app.pullMarkerPos(app.markers_an);
            bn = app.pullMarkerPos(app.markers_bn);
            
            % Compute the function values
            f = app.evalFourierSinCos(an,bn,app.fbcos,app.fbsin);

            % Update the stem plots
            app.plot_an.YData = an;
            app.plot_bn.YData = bn;

            app.realPlot1.YData = f;
            app.realFade1.YData = f;
        end

        function coef2Moved(app,xp,yp)
            % Executes when a Fourier coefficient marker is moved
            % In the magnitude-phase definition 

            % Pull the coefficients
            An = app.pullMarkerPos(app.markers_An);
            phin = app.pullMarkerPos(app.markers_phin);

            % Update the stem plots
            app.plot_An.YData = An;
            app.plot_phin.YData = phin;
            
            % Compute the standard basis functions 
            % (the complex form is the go-between)
            cn = An/2.*exp(-1i*phin);
            an = 2*real(cn);
            bn = -2*imag(cn);
            an(1) = An(1);
            
            % Compute the function values
            f = app.evalFourierSinCos(an,bn,app.fbcosMP,app.fbsinMP);

            app.realPlot2.YData = f;
            app.realFade2.YData = f;
        end


        %% These are the generic plot functions
        % Reused by both panes

        function [markers,lines] = setUpFreqAxes(app,UIAxes,nvec,color,...
                markers,lines,xLbl,yLbl,callbackFunc)
            % Sets up the frequency domain with the moveable markers
            
            cla(UIAxes)
            markers = {};

            % Add plots to axis and save the handles
            hold(UIAxes,'on')
            % Create stem plot
            lines = stem(UIAxes,nvec,nvec*0,...
                'linewidth',1,'color',color,'marker','none');
            % Add markers
            for k = 1:numel(nvec)
                markers{k} = DraggablePoint2D(UIAxes,nvec(k),0,callbackFunc,2);
                set(markers{k}.lineHandle,... 
                     'markerfacecolor',color(1,:),...
                     'color',[0.8,0.8,0.8]);   
            end
            hold(UIAxes,'off')

            % Axis limits/modes
            xlim(UIAxes,[0,nvec(end)])
            xticks(UIAxes,nvec)
            xlabel(UIAxes,xLbl,'interpreter','latex')
            ylabel(UIAxes,yLbl,'interpreter','latex')
            disableDefaultInteractivity(UIAxes)
            axtoolbar(UIAxes,{});

        end

        function setUpRealAxes(app,UIAxes,xLbl,yLbl)
            % Sets up the UIAxes for the time domain 

            % Adjust the graph appearance
            xlim(UIAxes,[app.tvec(1),app.tvec(end)])
            ylim(UIAxes,app.reLims)
            xticks(UIAxes,[app.tvec(1), mean(app.tvec),app.tvec(end)])
            yticks(UIAxes,[app.reLims(1), mean(app.reLims),app.reLims(2)])
            xlabel(UIAxes,xLbl,'interpreter','latex')
            ylabel(UIAxes,yLbl,'interpreter','latex')
            disableDefaultInteractivity(UIAxes)
            axtoolbar(UIAxes,{});

        end

        function setupEquationAxes(app,UIAxes,eq,fontSize)
            % Adjusts the settings for the axes that shows the equation
            axis(UIAxes,"off")
            disableDefaultInteractivity(UIAxes)
            axtoolbar(UIAxes,{});
            cla(UIAxes)
            text(UIAxes,0.45,0.5,eq,"color",[0,0,0]*0.95,"Interpreter","latex",...
                "HorizontalAlignment","center",'FontSize',fontSize)
        end

        % Pull the coefficients
        function coef = pullMarkerPos(app,markers)
            % Extract the coefficients when a marker is moved
            coef = zeros(1,numel(markers));
            for k = 1:numel(markers)
                [~,coef(k)] = markers{k}.getPos();
            end
        end

        function clearRealAxes(app)
            cla(app.UIAxesMagPhase)
            cla(app.UIAxesSineCosine)
        end

        %% Math functions
        function [fbcos, fbsin, tvec, nvec] = sinCosBasis(app,Nfreq,Nt,P)
            % Compute the fourier series sine/cosine basis
            % Nf is the number of frequencies
            % Nt is the number of time points
            % P is the period (also the final time)

            % Time domain
            tvec = linspace(0,P,Nt)';            

            % P is the period
            nvec = 0:Nfreq;
            freq = 1/P*nvec;

            % Basis functions
            fbcos = cos(2*pi*freq.*tvec);
            fbsin = sin(2*pi*freq.*tvec);
        end

         % Evaluate Fourier series
         function [yFourier] = evalFourierSinCos(app,a,b,fbcos,fbsin)
            % Compute the fourier series
            yFourier = sum(a.*fbcos + b.*fbsin,2);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setUpSinCos
            app.setUpMagPhase
            app.createRealPlots

        end

        % Value changed function: NSpinnerSineCosine
        function NSpinnerSineCosineValueChanged(app, event)
            app.setUpSinCos
            app.coef1Moved([],[])
            app.coef2Moved([],[])
        end

        % Button pushed function: ClearButtonSineCosine
        function ClearButtonSineCosinePushed(app, event)
            app.setUpSinCos
            app.coef1Moved([],[])
            app.coef2Moved([],[])
        end

        % Value changed function: NSpinnerMagPhase
        function NSpinnerMagPhaseValueChanged(app, event)
            app.setUpMagPhase
            app.coef1Moved([],[])
            app.coef2Moved([],[])
        end

        % Button pushed function: ClearButtonMagPhase
        function ClearButtonMagPhasePushed(app, event)
            app.setUpMagPhase
            app.coef1Moved([],[])
            app.coef2Moved([],[])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FourierSeriesMagnitudeandPhaseUIFigure = uifigure('Visible', 'off');
            app.FourierSeriesMagnitudeandPhaseUIFigure.Name = 'Fourier Series: Magnitude and Phase';
            app.GridLayout = uigridlayout(app.FourierSeriesMagnitudeandPhaseUIFigure);
            app.DominiodelFrecuenciaSeriedeSenoyCosenoPanel = uipanel(app.GridLayout);
            app.DominiodelFrecuenciaSeriedeSenoyCosenoPanel.Title = 'Dominio del Frecuencia: Serie de Seno y Coseno';
            app.GridLayout3 = uigridlayout(app.DominiodelFrecuenciaSeriedeSenoyCosenoPanel);
            app.UIAxes_bn = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes_bn, 't')
            ylabel(app.UIAxes_bn, 'y')
            zlabel(app.UIAxes_bn, 'Z')
            app.UIAxes_bn.XLim = [0 8];
            app.UIAxes_bn.YLim = [-1 1];
            app.UIAxes_an = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes_an, 't')
            ylabel(app.UIAxes_an, 'y')
            zlabel(app.UIAxes_an, 'Z')
            app.UIAxes_an.XLim = [0 8];
            app.UIAxes_an.YLim = [-1 1];
            app.NLabel = uilabel(app.GridLayout3);
            app.NLabel.Text = 'N:';
            app.NSpinnerSineCosine = uispinner(app.GridLayout3);
            app.NSpinnerSineCosine.Limits = [1 20];
            app.NSpinnerSineCosine.ValueChangedFcn = createCallbackFcn(app, @NSpinnerSineCosineValueChanged, true);
            app.NSpinnerSineCosine.Value = 6;
            app.ClearButtonSineCosine = uibutton(app.GridLayout3, 'push');
            app.ClearButtonSineCosine.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonSineCosinePushed, true);
            app.ClearButtonSineCosine.Text = 'Limpiar';
            app.DominiodelFrecuenciaSeriedeMagnitudyFasePanel = uipanel(app.GridLayout);
            app.DominiodelFrecuenciaSeriedeMagnitudyFasePanel.Title = 'Dominio del Frecuencia: Serie de Magnitud y Fase';
            app.GridLayout6 = uigridlayout(app.DominiodelFrecuenciaSeriedeMagnitudyFasePanel);
            app.UIAxes_An = uiaxes(app.GridLayout6);
            xlabel(app.UIAxes_An, 't')
            ylabel(app.UIAxes_An, 'y')
            zlabel(app.UIAxes_An, 'Z')
            app.UIAxes_An.XLim = [0 8];
            app.UIAxes_An.YLim = [-1 1];
            app.UIAxes_phin = uiaxes(app.GridLayout6);
            xlabel(app.UIAxes_phin, 't')
            ylabel(app.UIAxes_phin, 'y')
            zlabel(app.UIAxes_phin, 'Z')
            app.UIAxes_phin.XLim = [0 8];
            app.UIAxes_phin.YLim = [-1 1];
            app.ClearButtonMagPhase = uibutton(app.GridLayout6, 'push');
            app.ClearButtonMagPhase.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonMagPhasePushed, true);
            app.ClearButtonMagPhase.Text = 'Limpiar';
            app.NSpinner_2Label = uilabel(app.GridLayout6);
            app.NSpinner_2Label.Text = 'N:';
            app.NSpinnerMagPhase = uispinner(app.GridLayout6);
            app.NSpinnerMagPhase.Limits = [1 20];
            app.NSpinnerMagPhase.ValueChangedFcn = createCallbackFcn(app, @NSpinnerMagPhaseValueChanged, true);
            app.NSpinnerMagPhase.Value = 6;
            app.DominiodelTiempoSeriedeSenoyCosenoPanel = uipanel(app.GridLayout);
            app.DominiodelTiempoSeriedeSenoyCosenoPanel.Title = 'Dominio del Tiempo: Serie de Seno y Coseno';
            app.GridLayout4 = uigridlayout(app.DominiodelTiempoSeriedeSenoyCosenoPanel);
            app.EqAxesSineCosine = uiaxes(app.GridLayout4);
            app.EqAxesSineCosine.Visible = 'off';
            app.UIAxesSineCosine = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesSineCosine, 'X')
            ylabel(app.UIAxesSineCosine, 'Y')
            zlabel(app.UIAxesSineCosine, 'Z')
            app.DefinitionPanelSineCosine = uipanel(app.GridLayout4);
            app.DefinitionPanelSineCosine.Title = 'Definición';
            app.DominiodelTiempoSeriedeMagnitudyFasePanel = uipanel(app.GridLayout);
            app.DominiodelTiempoSeriedeMagnitudyFasePanel.Title = 'Dominio del Tiempo: Serie de Magnitud y Fase';
            app.GridLayout5 = uigridlayout(app.DominiodelTiempoSeriedeMagnitudyFasePanel);
            app.EqAxesMagPhase = uiaxes(app.GridLayout5);
            app.EqAxesMagPhase.Visible = 'off';
            app.UIAxesMagPhase = uiaxes(app.GridLayout5);
            xlabel(app.UIAxesMagPhase, 'X')
            ylabel(app.UIAxesMagPhase, 'Y')
            zlabel(app.UIAxesMagPhase, 'Z')
            app.DefinitionPanelMagPhase = uipanel(app.GridLayout5);
            app.DefinitionPanelMagPhase.Title = 'Definición';
            % Show the figure after all components are created
            app.FourierSeriesMagnitudeandPhaseUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MagnitudePhase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FourierSeriesMagnitudeandPhaseUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FourierSeriesMagnitudeandPhaseUIFigure)
        end
    end
end